classdef app1_ege < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        SNFN          matlab.ui.control.Label
        Subjects      matlab.ui.control.Label
        Scores        matlab.ui.control.Label
        Surname       matlab.ui.control.EditField
        Name          matlab.ui.control.EditField
        Father_name   matlab.ui.control.EditField
        Subject_1     matlab.ui.control.EditField
        Subject_2     matlab.ui.control.EditField
        Subject_3     matlab.ui.control.EditField
        Score_1       matlab.ui.control.NumericEditField
        Score_2       matlab.ui.control.NumericEditField
        Score_3       matlab.ui.control.NumericEditField
        Label_4       matlab.ui.control.Label
        Prohod_score  matlab.ui.control.Label
        Message       matlab.ui.control.Label
        Button        matlab.ui.control.Button
        Sum           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Score_1
        function Score_1ValueChanged(app, event)

        end

        % Value changed function: Score_2
        function Score_2ValueChanged(app, event)

        end

        % Value changed function: Score_3
        function Score_3ValueChanged(app, event)

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            s1 = app.Score_1.Value;
            if s1 < 0 || s1 > 100
                errordlg("Я вам запрещаю вводить такое число")
                return
            end
            s2 = app.Score_2.Value;
            if s2 < 0 || s2 > 100
                errordlg("Я вам запрещаю вводить такое число")
                return
            end
            s3 = app.Score_3.Value;
            if s3 < 0 || s3 > 100
                errordlg("Я вам запрещаю вводить такое число")
                return
            end
            s = s1 + s2 + s3;
            app.Sum.Text = num2str(s);
            if s >= 250
                app.Message.Text = "YeEeEe boiii";
            else
                app.Message.Text = ":( "
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SNFN = uilabel(app.UIFigure);
            app.SNFN.Text = 'Фамилия, имя, отчество';
            app.Subjects = uilabel(app.UIFigure);
            app.Subjects.Text = 'Предмет';
            app.Scores = uilabel(app.UIFigure);
            app.Scores.Text = 'Баллы ЕГЭ';
            app.Surname = uieditfield(app.UIFigure, 'text');
            app.Name = uieditfield(app.UIFigure, 'text');
            app.Father_name = uieditfield(app.UIFigure, 'text');
            app.Subject_1 = uieditfield(app.UIFigure, 'text');
            app.Subject_2 = uieditfield(app.UIFigure, 'text');
            app.Subject_3 = uieditfield(app.UIFigure, 'text');
            app.Score_1 = uieditfield(app.UIFigure, 'numeric');
            app.Score_1.ValueChangedFcn = createCallbackFcn(app, @Score_1ValueChanged, true);
            app.Score_2 = uieditfield(app.UIFigure, 'numeric');
            app.Score_2.ValueChangedFcn = createCallbackFcn(app, @Score_2ValueChanged, true);
            app.Score_3 = uieditfield(app.UIFigure, 'numeric');
            app.Score_3.ValueChangedFcn = createCallbackFcn(app, @Score_3ValueChanged, true);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Сумма баллов:';
            app.Prohod_score = uilabel(app.UIFigure);
            app.Prohod_score.Text = 'Проходной балл: 250';
            app.Message = uilabel(app.UIFigure);
            app.Message.Text = {''; ''};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Подсчёт баллов';
            app.Sum = uilabel(app.UIFigure);
            app.Sum.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_ege

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end