classdef app2_sincos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        UIAxes     matlab.ui.control.UIAxes
        Panel      matlab.ui.container.Panel
        Check_sin  matlab.ui.control.CheckBox
        Check_cos  matlab.ui.control.CheckBox
        Button     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Check_sin
        function Check_sinValueChanged(app, event)
         
        end

        % Value changed function: Check_cos
        function Check_cosValueChanged(app, event)
         
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            x = 0:0.01:4*pi;
            y1 = sin(x);
            y2 = cos(x);
            plot1 = plot(app.UIAxes, x, y1);
            hold(app.UIAxes,'on');
            plot2 = plot(app.UIAxes, x, y2);
            if (app.Check_sin.Value == false)
              delete(plot1);
            end
            if (app.Check_cos.Value == false)
              delete(plot2);
            end
            hold(app.UIAxes,'off')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.Panel = uipanel(app.UIFigure);
            app.Check_sin = uicheckbox(app.Panel);
            app.Check_sin.ValueChangedFcn = createCallbackFcn(app, @Check_sinValueChanged, true);
            app.Check_sin.Text = 'График синуса';
            app.Check_cos = uicheckbox(app.Panel);
            app.Check_cos.ValueChangedFcn = createCallbackFcn(app, @Check_cosValueChanged, true);
            app.Check_cos.Text = 'График косинуса';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Нарисовать';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2_sincos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end