classdef app3_gausssin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Gauss      matlab.ui.control.UIAxes
        Sine       matlab.ui.control.UIAxes
        Panel      matlab.ui.container.Panel
        Build_1    matlab.ui.control.Button
        Destroy_1  matlab.ui.control.Button
        Panel_2    matlab.ui.container.Panel
        Build_2    matlab.ui.control.Button
        Destroy_2  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Build_1
        function Build_1ButtonPushed(app, event)
            x = -2:0.2:2;
            y = exp(-x.^2);
            plot(app.Gauss, x, y);
        end

        % Button pushed function: Destroy_1
        function Destroy_1ButtonPushed(app, event)
            x = -2:0.2:2;
            y = exp(-x.^2);
            kek = plot(app.Gauss, x, y);
            delete(kek);
        end

        % Button pushed function: Build_2
        function Build_2ButtonPushed(app, event)
            x = 0:0.1:4*pi;
            y = sin(x);
            plot(app.Sine, x, y);
        end

        % Button pushed function: Destroy_2
        function Destroy_2ButtonPushed(app, event)
            x = 0:0.1:4*pi;
            y = sin(x);
            kek = plot(app.Sine, x, y);
            delete(kek);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Gauss = uiaxes(app.UIFigure);
            title(app.Gauss, 'Гауссиан')
            xlabel(app.Gauss, '')
            ylabel(app.Gauss, '')
            app.Sine = uiaxes(app.UIFigure);
            title(app.Sine, 'Синус')
            xlabel(app.Sine, '')
            ylabel(app.Sine, '')
            app.Panel = uipanel(app.UIFigure);
            app.Build_1 = uibutton(app.Panel, 'push');
            app.Build_1.ButtonPushedFcn = createCallbackFcn(app, @Build_1ButtonPushed, true);
            app.Build_1.Text = 'Построить';
            app.Destroy_1 = uibutton(app.Panel, 'push');
            app.Destroy_1.ButtonPushedFcn = createCallbackFcn(app, @Destroy_1ButtonPushed, true);
            app.Destroy_1.Text = {'Очистить'; ''};
            app.Panel_2 = uipanel(app.UIFigure);
            app.Build_2 = uibutton(app.Panel_2, 'push');
            app.Build_2.ButtonPushedFcn = createCallbackFcn(app, @Build_2ButtonPushed, true);
            app.Build_2.Text = 'Построить';
            app.Destroy_2 = uibutton(app.Panel_2, 'push');
            app.Destroy_2.ButtonPushedFcn = createCallbackFcn(app, @Destroy_2ButtonPushed, true);
            app.Destroy_2.Text = {'Очистить'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3_gausssin

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end